classdef peakPickingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        CurrentModalFrequencyEstimatesLabel  matlab.ui.control.Label
        modalFreqtab                  matlab.ui.control.Table
        RemoveModeSpinner             matlab.ui.control.Spinner
        RemoveModeSpinnerLabel        matlab.ui.control.Label
        ChangeFittingBandwidthEditField  matlab.ui.control.NumericEditField
        ChangeFittingBandwidthEditFieldLabel  matlab.ui.control.Label
        modeField                     matlab.ui.control.NumericEditField
        AddModeButton                 matlab.ui.control.Button
        removeModeButton              matlab.ui.control.Button
        changeBandButton              matlab.ui.control.Button
        RefineIterationButton         matlab.ui.control.Button
        applyAddModeButton            matlab.ui.control.Button
        FilenameEditField             matlab.ui.control.EditField
        FilenameEditFieldLabel        matlab.ui.control.Label
        ActionsLabel                  matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        ModalAnalyserAppLabel         matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        AutoupdatebandwidthsCheckBox  matlab.ui.control.CheckBox
        AutoupdateImpedancePlotCheckBox  matlab.ui.control.CheckBox
        PlotsLabel                    matlab.ui.control.Label
        HideFittingBandwidthButton    matlab.ui.control.Button
        ShowFittingBandwidthButton    matlab.ui.control.Button
        PlotControlsLabel             matlab.ui.control.Label
        HideImpedancePlotButton       matlab.ui.control.Button
        ShowImpedancePlotButton       matlab.ui.control.Button
        errorAxes                     matlab.ui.control.UIAxes
        impedanceAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        modalAnalyser % Store Modal analyser class
        freqRange % range of frequencies to plot
        modalPlothandle % handle for modal plot
        bandPlothandle % handle for bandwidth plot
        errorPlothandle % handle for error plot
    end
    
    methods (Access = private)
        
        function app = updateImpedancePlot(app)
            %UPDATEIMPEDANCEPLOT - Update the plot of the simulated
            %impedance
            % Update plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Parse data
            data = app.modalAnalyser.simImpedance;
            
            % Plot new impedance estimate
            figure(app.UIFigure)
            hold(app.impedanceAxes,'on')
            app.modalPlothandle = plot(app.impedanceAxes,real(data(1:16385,1)), 20*log10(abs(data(1:16385,2))), 'k');
            hold(app.impedanceAxes,'off')
        end
        
        function app = updateFittingBandwidthPlot(app)
            %UPDATEIMPEDANCEPLOT - Update the plot of the fitted bandwidth
            
            % Remove existing bandwidth plot
            if ishandle(app.bandPlothandle)
                delete(app.bandPlothandle)
            end
            
            % Get bandwidth and freqMeasurements
            bandwidth = app.modalAnalyser.v;

            % Find points of discontinuity
            logicIdx = ischange(bandwidth,'variance','threshold',app.modalAnalyser.fittingBandwidth*2);

            % Get beginning and end points
            tmp = 1:length(bandwidth);
            idxLoc = tmp(logicIdx);

            % Get all indices to graph bandwidth
            idxLoc = union(idxLoc,idxLoc - 1);
            idxLoc(idxLoc < 1) = idxLoc(idxLoc < 1) + 1;

            % Check if first frequency is in the bandwidth
            if ~all(idxLoc==1)
                idxLoc = [1 idxLoc];
            end

            % Check if the last frequency in the bandwidth
            if ~all(idxLoc==length(tmp))
                idxLoc = [idxLoc length(tmp)];
            end

            % Check if it is empty
            if isempty(idxLoc)
                idxLoc = [1 length(tmp)];
            end

            % Find frequency values
            freqBands = bandwidth(idxLoc);

            % Plot bandwidth
            app.bandPlothandle = xline(app.impedanceAxes,freqBands,'--r');
            
        end

        function app = updateErrorPlot(app)
            %UPDATEERRORPLOT - update plot showing current difference
            %between estimated impedance and measured impedance
            % x = frequency
            % y = error

            % Get frequencies
            freq = app.modalAnalyser.impMeasurement(:,1);

            % Get measured and simulated impedance in dB
            simImp = 20*log10(abs(app.modalAnalyser.simImpedance(:,2)));
            measImp = 20*log10(abs(app.modalAnalyser.impMeasurement(:,2)));

            % Compute relative error
            relError = abs(simImp-measImp);

            % Plot the graph
            % Remove existing error plot
            if ishandle(app.errorPlothandle)
                delete(app.errorPlothandle)
            end

            % Plot error
            app.errorPlothandle = plot(app.errorAxes,freq(1:16385),relError(1:16385));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.FilenameEditField.Value = filename;
            
            % Load file
            load(fullfile(path,filename),'f','Z')
            titlestr = sprintf("Impedance data from %s",filename);

            % Plot file
            figure(app.UIFigure)
            plot(app.impedanceAxes,f(1:16385), 20*log10(abs(Z(1:16385))), 'b')
            xlim(app.impedanceAxes,[0 5000])
            xlabel(app.impedanceAxes,'Frequency (Hz)')
            ylabel(app.impedanceAxes,'Impedance / Zc (dB)')
            title(app.impedanceAxes,titlestr)

            % show data tips
            datacursormode(app.UIFigure,'on')

            % Start Modal Analyser
            app.modalAnalyser = modalAnalyser(0.05,50,[f transpose(Z)]);

            % Save frequency range
            app.freqRange = f;
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            value = app.FilenameEditField.Value;
            
        end

        % Button down function: impedanceAxes
        function impedanceAxesButtonDown(app, event)

        end

        % Button pushed function: AddModeButton
        function AddModeButtonPushed(app, event)
            % code taken from https://www.mathworks.com/matlabcentral/answers/392617-how-can-i-use-ginput-in-app-designer
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')
            
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            
            [x,~,~] = ginput(1);                        % run ginput
            
            app.UIFigure.HandleVisibility = fhv;        % return original state

            % Changes value in text field
            app.modeField.Value = x;
        end

        % Value changed function: modeField
        function modeFieldValueChanged(app, event)
            value = app.modeField.Value;
            
        end

        % Button pushed function: applyAddModeButton
        function applyAddModeButtonPushed(app, event)
            % Add the mode
            app.modalAnalyser.addMode(app.modeField.Value);

            % Update the values for plotting
            app.modalAnalyser.impedancePlot(app.freqRange);

            % Update spinnder
            if size(app.modalAnalyser.modalFrequency,1) > 2
                app.RemoveModeSpinner.Limits = [1 length(app.modalAnalyser.modalFrequency)];
            end

            % Update table
            tabData = app.modalFreqtab.Data;
            tabData(end+1,:) = [app.modalAnalyser.poleIdx app.modalAnalyser.modalFrequency(app.modalAnalyser.poleIdx,1) app.modalAnalyser.modalFrequency(app.modalAnalyser.poleIdx,2)*100];
            app.modalFreqtab.Data = tabData;

            % Update impedance plot
            if app.AutoupdateImpedancePlotCheckBox.Value
                app.updateImpedancePlot();
            end

            % Update fitting bandwidth plot
            if app.AutoupdatebandwidthsCheckBox.Value
                app.updateFittingBandwidthPlot();
            end

            % Update Error plot
            app.updateErrorPlot();
        end

        % Button pushed function: ShowImpedancePlotButton
        function ShowImpedancePlotButtonPushed(app, event)
            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Parse data
            data = app.modalAnalyser.simImpedance;
            
            % Plot new impedance estimate
            figure(app.UIFigure)
            hold(app.impedanceAxes,'on')
            app.modalPlothandle = plot(app.impedanceAxes,data(:,1), 20*log10(abs(data(:,2))), 'k');
            hold(app.impedanceAxes,'off')
        end

        % Button pushed function: HideImpedancePlotButton
        function HideImpedancePlotButtonPushed(app, event)
            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

        end

        % Button pushed function: changeBandButton
        function changeBandButtonPushed(app, event)
            app.modalAnalyser.changeBandwidth(app.ChangeFittingBandwidthEditField.Value)

            % Update fitting bandwidth plot
            if app.AutoupdatebandwidthsCheckBox.Value
                app.updateFittingBandwidthPlot();
            end
        end

        % Value changed function: ChangeFittingBandwidthEditField
        function ChangeFittingBandwidthEditFieldValueChanged(app, event)
            value = app.ChangeFittingBandwidthEditField.Value;
            
        end

        % Button pushed function: RefineIterationButton
        function RefineIterationButtonPushed(app, event)
            % Refine the identification
            app.modalAnalyser.refineIdentification();

            % Update table
            tabData = [transpose(1:size(app.modalAnalyser.modalFrequency,1)) app.modalAnalyser.modalFrequency(:,1) round(app.modalAnalyser.modalFrequency(:,2)*100,2)];
            app.modalFreqtab.Data = tabData;

            % Update the values for plotting
            app.modalAnalyser.impedancePlot(app.freqRange);

            % Update impedance plot
            if app.AutoupdateImpedancePlotCheckBox.Value
                app.updateImpedancePlot();
            end

            % Update fitting bandwidth plot
            if app.AutoupdatebandwidthsCheckBox.Value
                app.updateFittingBandwidthPlot();
            end

            % Update error plot
            app.updateErrorPlot();
        end

        % Button pushed function: removeModeButton
        function removeModeButtonPushed(app, event)
            % Remove mode
            app.modalAnalyser.removeMode(app.RemoveModeSpinner.Value)

            % Update the values for plotting
            app.modalAnalyser.impedancePlot(app.freqRange);

            % Update spinnder
            if size(app.modalAnalyser.modalFrequency,1) >= 2
                app.RemoveModeSpinner.Limits = [1 length(app.modalAnalyser.modalFrequency)];
            end

            % Update table
            tabData = [transpose(1:size(app.modalAnalyser.modalFrequency,1)) app.modalAnalyser.modalFrequency(:,1) round(app.modalAnalyser.modalFrequency(:,2)*100,2)];
            app.modalFreqtab.Data = tabData;

            % Update impedance plot
            if app.AutoupdateImpedancePlotCheckBox.Value
                app.updateImpedancePlot();
            end

            % Update fitting bandwidth plot
            if app.AutoupdatebandwidthsCheckBox.Value
                app.updateFittingBandwidthPlot();
            end

            % Update error plot
            app.updateErrorPlot();
        end

        % Display data changed function: modalFreqtab
        function modalFreqtabDisplayDataChanged(app, event)
            newDisplayData = app.modalFreqtab.DisplayData;
            
        end

        % Button pushed function: ShowFittingBandwidthButton
        function ShowFittingBandwidthButtonPushed(app, event)
            app.updateFittingBandwidthPlot();
        end

        % Button pushed function: HideFittingBandwidthButton
        function HideFittingBandwidthButtonPushed(app, event)
            % Remove existing bandwidth plot
            if ishandle(app.bandPlothandle)
                delete(app.bandPlothandle)
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset app

            % Delete all plot handles
            % Remove existing bandwidth plot
            if ishandle(app.bandPlothandle)
                delete(app.bandPlothandle)
            end

            % Remove existing impedance plot
            if ishandle(app.modalPlothandle)
                delete(app.modalPlothandle)
            end

            % Remove existing error plot
            if ishandle(app.errorPlothandle)
                delete(app.errorPlothandle)
            end

            % Reset modal analyser class
            app.modalAnalyser = modalAnalyser(0.05,50,app.modalAnalyser.impMeasurement);

            % Reset table
            app.modalFreqtab.Data = [];

            % Reset spinner
            app.RemoveModeSpinner.Limits = [1 2];
        end

        % Value changed function: AutoupdateImpedancePlotCheckBox
        function AutoupdateImpedancePlotCheckBoxValueChanged(app, event)
            value = app.AutoupdateImpedancePlotCheckBox.Value;
            
        end

        % Value changed function: AutoupdatebandwidthsCheckBox
        function AutoupdatebandwidthsCheckBoxValueChanged(app, event)
            value = app.AutoupdatebandwidthsCheckBox.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {864, 864};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ModalAnalyserAppLabel = uilabel(app.LeftPanel);
            app.ModalAnalyserAppLabel.Text = 'Modal Analyser App';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ActionsLabel = uilabel(app.LeftPanel);
            app.ActionsLabel.Text = 'Actions';
            app.FilenameEditFieldLabel = uilabel(app.LeftPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.applyAddModeButton = uibutton(app.LeftPanel, 'push');
            app.applyAddModeButton.ButtonPushedFcn = createCallbackFcn(app, @applyAddModeButtonPushed, true);
            app.applyAddModeButton.Text = 'Apply';
            app.RefineIterationButton = uibutton(app.LeftPanel, 'push');
            app.RefineIterationButton.ButtonPushedFcn = createCallbackFcn(app, @RefineIterationButtonPushed, true);
            app.RefineIterationButton.Text = 'Refine Iteration';
            app.changeBandButton = uibutton(app.LeftPanel, 'push');
            app.changeBandButton.ButtonPushedFcn = createCallbackFcn(app, @changeBandButtonPushed, true);
            app.changeBandButton.Text = 'Apply';
            app.removeModeButton = uibutton(app.LeftPanel, 'push');
            app.removeModeButton.ButtonPushedFcn = createCallbackFcn(app, @removeModeButtonPushed, true);
            app.removeModeButton.Text = 'Apply';
            app.AddModeButton = uibutton(app.LeftPanel, 'push');
            app.AddModeButton.ButtonPushedFcn = createCallbackFcn(app, @AddModeButtonPushed, true);
            app.AddModeButton.Text = 'Add Mode';
            app.modeField = uieditfield(app.LeftPanel, 'numeric');
            app.modeField.ValueChangedFcn = createCallbackFcn(app, @modeFieldValueChanged, true);
            app.ChangeFittingBandwidthEditFieldLabel = uilabel(app.LeftPanel);
            app.ChangeFittingBandwidthEditFieldLabel.Text = 'Change Fitting Bandwidth';
            app.ChangeFittingBandwidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ChangeFittingBandwidthEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeFittingBandwidthEditFieldValueChanged, true);
            app.RemoveModeSpinnerLabel = uilabel(app.LeftPanel);
            app.RemoveModeSpinnerLabel.Text = 'Remove Mode';
            app.RemoveModeSpinner = uispinner(app.LeftPanel);
            app.RemoveModeSpinner.Limits = [1 2];
            app.RemoveModeSpinner.Value = 1;
            app.modalFreqtab = uitable(app.LeftPanel);
            app.modalFreqtab.ColumnName = {'Mode'; 'Frequency (Hz)'; 'Damping (%)'};
            app.modalFreqtab.RowName = {};
            app.modalFreqtab.ColumnSortable = true;
            app.modalFreqtab.ColumnEditable = true;
            app.modalFreqtab.DisplayDataChangedFcn = createCallbackFcn(app, @modalFreqtabDisplayDataChanged, true);
            app.CurrentModalFrequencyEstimatesLabel = uilabel(app.LeftPanel);
            app.CurrentModalFrequencyEstimatesLabel.Text = 'Current Modal Frequency Estimates';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.impedanceAxes = uiaxes(app.RightPanel);
            title(app.impedanceAxes, 'Title')
            xlabel(app.impedanceAxes, 'X')
            ylabel(app.impedanceAxes, 'Y')
            zlabel(app.impedanceAxes, 'Z')
            app.impedanceAxes.ButtonDownFcn = createCallbackFcn(app, @impedanceAxesButtonDown, true);
            app.errorAxes = uiaxes(app.RightPanel);
            title(app.errorAxes, 'Absolute Error')
            xlabel(app.errorAxes, 'Frequency (Hz)')
            ylabel(app.errorAxes, 'Error (dB)')
            zlabel(app.errorAxes, 'Z')
            app.errorAxes.XLim = [0 5000];
            app.ShowImpedancePlotButton = uibutton(app.RightPanel, 'push');
            app.ShowImpedancePlotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowImpedancePlotButtonPushed, true);
            app.ShowImpedancePlotButton.Text = 'Show Impedance Plot';
            app.HideImpedancePlotButton = uibutton(app.RightPanel, 'push');
            app.HideImpedancePlotButton.ButtonPushedFcn = createCallbackFcn(app, @HideImpedancePlotButtonPushed, true);
            app.HideImpedancePlotButton.Text = 'Hide Impedance Plot';
            app.PlotControlsLabel = uilabel(app.RightPanel);
            app.PlotControlsLabel.Text = 'Plot Controls';
            app.ShowFittingBandwidthButton = uibutton(app.RightPanel, 'push');
            app.ShowFittingBandwidthButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFittingBandwidthButtonPushed, true);
            app.ShowFittingBandwidthButton.Text = 'Show Fitting Bandwidth';
            app.HideFittingBandwidthButton = uibutton(app.RightPanel, 'push');
            app.HideFittingBandwidthButton.ButtonPushedFcn = createCallbackFcn(app, @HideFittingBandwidthButtonPushed, true);
            app.HideFittingBandwidthButton.Text = 'Hide Fitting Bandwidth';
            app.PlotsLabel = uilabel(app.RightPanel);
            app.PlotsLabel.Text = 'Plots';
            app.AutoupdateImpedancePlotCheckBox = uicheckbox(app.RightPanel);
            app.AutoupdateImpedancePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoupdateImpedancePlotCheckBoxValueChanged, true);
            app.AutoupdateImpedancePlotCheckBox.Text = 'Auto-update Impedance Plot';
            app.AutoupdatebandwidthsCheckBox = uicheckbox(app.RightPanel);
            app.AutoupdatebandwidthsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoupdatebandwidthsCheckBoxValueChanged, true);
            app.AutoupdatebandwidthsCheckBox.Text = 'Auto-update bandwidths';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = peakPickingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end